classdef Eye_tracker_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        GammaSpinner          matlab.ui.control.Spinner
        GammaSpinnerLabel     matlab.ui.control.Label
        ExposureSpinner       matlab.ui.control.Spinner
        ExposureSpinnerLabel  matlab.ui.control.Label
        GainSpinner           matlab.ui.control.Spinner
        GainSpinnerLabel      matlab.ui.control.Label
        CameraModeCheckBox    matlab.ui.control.CheckBox
        CenterButton          matlab.ui.control.Button
        OutputCheckBox        matlab.ui.control.CheckBox
        BoxSizeSlider         matlab.ui.control.Slider
        BoxSizeSliderLabel    matlab.ui.control.Label
        MP4SimulateCheckBox   matlab.ui.control.CheckBox
        ThresSlider           matlab.ui.control.Slider
        ThresSliderLabel      matlab.ui.control.Label
        StartTrackButton      matlab.ui.control.Button
        ThresholdCheckBox     matlab.ui.control.CheckBox
        RightPanel            matlab.ui.container.Panel
        Image                 matlab.ui.control.Image
        CXCYLabel             matlab.ui.control.Label
        SRLabel               matlab.ui.control.Label
        DesignedbyMoonLLabel  matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        vidObj
        terminate_while = 0;
        update_monitor=0;
        daqs
        handel_image
        centerx=157;
        centery = 193;
        box_size = 80;
        pupil_size = 4;
        X
        Y
        X_ratio=0;
        Y_ratio=0;
        voltage_gain = 3;
        fresh_int = 20
        resx = 640;
        resy = 480;
    end
    
    methods (Access = private)
        function subplotCallback(app,~,eventdata)
            if(eventdata.Button==1) % left click
                app.centerx = floor(eventdata.IntersectionPoint(2));
                app.centerx = max(app.centerx, app.box_size+app.pupil_size+3);
                app.centery = floor(eventdata.IntersectionPoint(1));
                app.centery = max(app.centery, app.box_size+app.pupil_size+3);
            end
            app.CXCYLabel.Text = sprintf('CX=%d CY = %d',app.centerx,app.centery);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            global vid
            try
                delete(vid)
            end
            axis(app.UIAxes, 'off');
            title(app.UIAxes,'Camera Image')
            %hold(app.UIAxes, 'on');
            videoFile = '85c97f514e837841c956e55d7142bdd4.mp4'; % 替换为你的MP4文件路径
            app.vidObj = VideoReader(videoFile);

            try
                app.daqs = daq.createSession('ni');
                addAnalogOutputChannel(app.daqs, 'Dev1', 'ao0', 'Voltage'); % 输出x坐标
                addAnalogOutputChannel(app.daqs, 'Dev1', 'ao1', 'Voltage'); % 输出y坐标
            catch
                release(app.daqs)
            end
            app.handel_image = imshow(rand([320,240])','Parent',app.UIAxes);
            set(app.handel_image, 'ButtonDownFcn', @(src, event) subplotCallback(app,src,event));

            vid = videoinput("winvideo",1,sprintf('RGB24_%dx%d', app.resx, app.resy)); 
            src = getselectedsource(vid);
            src.Gamma=app.GammaSpinner.Value;
            src.ExposureMode='manual';
            src.Exposure=app.ExposureSpinner.Value;
            src.Gain=app.GainSpinner.Value;
            set(vid,'FramesPerTrigger',1)
            set(vid,'ReturnedColorSpace','grayscale')
            set(vid,'TriggerRepeat',inf)
            start(vid)
        end

        % Button pushed function: StartTrackButton
        function StartTrackButtonPushed(app, event)
            global vid
            while(~app.terminate_while)
            tic
            if(app.MP4SimulateCheckBox.Value)
                if hasFrame(app.vidObj)
                    img = readFrame(app.vidObj);
                    %img = imresize(img,[200,200]);
                else
                    videoFile = '85c97f514e837841c956e55d7142bdd4.mp4'; % 替换为你的MP4文件路径
                    app.vidObj = VideoReader(videoFile);
                    img = readFrame(app.vidObj);
                    %img = imresize(img,[200,200]);
                end
                g_image=rgb2gray(img);
            else
                % load camera data
                g_image = imresize(getsnapshot(vid),0.5);
            end


                    cropX = max(1,floor(app.centerx - app.box_size));
                    cropY = max(1,floor(app.centery - app.box_size));
                    bw_image = ~imbinarize(g_image,app.ThresSlider.Value); 
                    crop_bw_image = imcrop(bw_image, [cropY, cropX, 2*app.box_size, 2*app.box_size]);
                    fill_image=imfill(crop_bw_image,'holes'); 
                    findout_area=regionprops(fill_image);
                    areas=[findout_area.Area];
                    [~, pam_max]=max(areas);
                
                    % check which image to show
                    if(app.ThresholdCheckBox.Value)
                        im_to_show = bw_image;
                        eye_color = 0;
                    else
                        im_to_show = g_image;
                        eye_color = 255;
                    end

                    % judge eye location
                    if(isempty(pam_max))
                        app.X = app.centerx-app.box_size;
                        app.Y = app.centery-app.box_size;
                        centro = [0.1,0.1];
                    else
                        centro=findout_area(pam_max).Centroid;
                        app.X=centro(2)+app.centerx-app.box_size;
                        app.Y=centro(1)+app.centery-app.box_size;
                    end

                    % send eye location
                    if(app.OutputCheckBox.Value)
                        app.X_ratio = -app.voltage_gain*(centro(2)-app.box_size)./app.box_size+2.5;
                        app.X_ratio = max(min(app.X_ratio,4.9),0.1);
                        app.Y_ratio = app.voltage_gain*(centro(1)-app.box_size)./app.box_size+2.5;
                        app.Y_ratio = max(min(app.Y_ratio,4.9),0.1);
                        outputSingleScan(app.daqs, [app.X_ratio,app.Y_ratio]);
                    end
                    
                    % update figure
                    if(mod(app.update_monitor,app.fresh_int)==0)
                        % add center
                        tic
                        img_pos_X = round(app.X); img_pos_Y = round(app.Y);
                            im_to_show(app.centerx-app.box_size,app.centery-app.box_size:app.centery+app.box_size)=1 ;
                            im_to_show(app.centerx+app.box_size,app.centery-app.box_size:app.centery+app.box_size)=1;
                            im_to_show(app.centerx-app.box_size:app.centerx+app.box_size,app.centery-app.box_size)=1;
                            im_to_show(app.centerx-app.box_size:app.centerx+app.box_size,app.centery+app.box_size)=1;
                        % add box
                            im_to_show(img_pos_X, img_pos_Y-app.pupil_size:img_pos_Y+app.pupil_size)=eye_color;
                            im_to_show(img_pos_X-app.pupil_size:img_pos_X+app.pupil_size,img_pos_Y)=eye_color;
                            if(~app.ThresholdCheckBox.Value)
                                im_to_show = double(im_to_show)/255;
                            end
                        set(app.handel_image,'CData', im_to_show)
                        scatter(app.UIAxes2, app.Y_ratio, app.X_ratio, 50,'filled')
                        drawnow
                    end

                    if(mod(app.update_monitor,2500)==0)
                        flushdata(vid)
                    end
                    app.SRLabel.Text=sprintf('FreshInterVal = %.02fhz, X = %.02fv, Y =  %.02fv', 1/toc, app.X_ratio, app.Y_ratio);
                    app.update_monitor = app.update_monitor+1;

            end
            app.terminate_while=0;
        end

        % Value changed function: BoxSizeSlider, CameraModeCheckBox, 
        % ...and 4 other components
        function AnyValueChanged(app, event)
            app.terminate_while=1;
            if(app.CameraModeCheckBox.Value)
                app.fresh_int = 1;
            else
                app.fresh_int = 20;
            end
            app.box_size = floor(app.BoxSizeSlider.Value);
            StartTrackButtonPushed(app)
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            app.terminate_while=1;
            app.centerx = floor(app.X);
            app.centery = floor(app.Y);
            app.CXCYLabel.Text = sprintf('CX=%d CY = %d',app.centerx,app.centery);
            StartTrackButtonPushed(app)
        end

        % Value changed function: ExposureSpinner, GainSpinner, 
        % ...and 1 other component
        function CameraValueChanged(app, event)
            global vid
            stop(vid)
            src = getselectedsource(vid);
            src.Exposure=app.ExposureSpinner.Value;
            src.Gamma=app.GammaSpinner.Value;
            src.Gain=app.GainSpinner.Value;
            start(vid)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {554, 554};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ThresholdCheckBox = uicheckbox(app.LeftPanel);
            app.ThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.ThresholdCheckBox.Text = 'Threshold';
            app.StartTrackButton = uibutton(app.LeftPanel, 'push');
            app.StartTrackButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrackButtonPushed, true);
            app.StartTrackButton.Text = 'StartTrack';
            app.ThresSliderLabel = uilabel(app.LeftPanel);
            app.ThresSliderLabel.Text = 'Thres';
            app.ThresSlider = uislider(app.LeftPanel);
            app.ThresSlider.Limits = [0 0.5];
            app.ThresSlider.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.ThresSlider.Value = 0.18;
            app.MP4SimulateCheckBox = uicheckbox(app.LeftPanel);
            app.MP4SimulateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.MP4SimulateCheckBox.Text = 'MP4 Simulate';
            app.BoxSizeSliderLabel = uilabel(app.LeftPanel);
            app.BoxSizeSliderLabel.Text = 'BoxSize';
            app.BoxSizeSlider = uislider(app.LeftPanel);
            app.BoxSizeSlider.Limits = [10 100];
            app.BoxSizeSlider.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.BoxSizeSlider.Value = 30;
            app.OutputCheckBox = uicheckbox(app.LeftPanel);
            app.OutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.OutputCheckBox.Text = 'Output';
            app.CenterButton = uibutton(app.LeftPanel, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.CameraModeCheckBox = uicheckbox(app.LeftPanel);
            app.CameraModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.CameraModeCheckBox.Text = 'Camera Mode';
            app.CameraModeCheckBox.Value = true;
            app.GainSpinnerLabel = uilabel(app.LeftPanel);
            app.GainSpinnerLabel.Text = 'Gain';
            app.GainSpinner = uispinner(app.LeftPanel);
            app.GainSpinner.Limits = [1 63];
            app.GainSpinner.ValueChangedFcn = createCallbackFcn(app, @CameraValueChanged, true);
            app.GainSpinner.Value = 62;
            app.ExposureSpinnerLabel = uilabel(app.LeftPanel);
            app.ExposureSpinnerLabel.Text = 'Exposure';
            app.ExposureSpinner = uispinner(app.LeftPanel);
            app.ExposureSpinner.Step = 0.5;
            app.ExposureSpinner.Limits = [1 12];
            app.ExposureSpinner.ValueChangedFcn = createCallbackFcn(app, @CameraValueChanged, true);
            app.ExposureSpinner.Value = 6;
            app.GammaSpinnerLabel = uilabel(app.LeftPanel);
            app.GammaSpinnerLabel.Text = 'Gamma';
            app.GammaSpinner = uispinner(app.LeftPanel);
            app.GammaSpinner.ValueChangedFcn = createCallbackFcn(app, @CameraValueChanged, true);
            app.GammaSpinner.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Location')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 5];
            app.UIAxes2.YLim = [0 5];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.DesignedbyMoonLLabel = uilabel(app.RightPanel);
            app.DesignedbyMoonLLabel.Text = 'Designed by MoonL';
            app.SRLabel = uilabel(app.RightPanel);
            app.SRLabel.Text = 'SR';
            app.CXCYLabel = uilabel(app.RightPanel);
            app.CXCYLabel.Text = 'CX CY';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LAB_logo.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eye_tracker_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end